classdef receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        fileName_text    matlab.ui.control.EditField
        read             matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        FFTButton        matlab.ui.control.StateButton
        FFT_decoded      matlab.ui.control.Label
        FFT_decoded_num  matlab.ui.control.Label
        playAudio        matlab.ui.control.Button
        GButton          matlab.ui.control.Button
        G_decoded        matlab.ui.control.Label
        G_decoded_num    matlab.ui.control.Label
        FFT_time         matlab.ui.control.Label
        G_time           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
            DTMF
            fileName
            DecodingOutPut_str
            y_sequence_All
            y_sequence
            num_of_all
            
            
            button1=[697,1209]
            button4=[770,1209]
            button7=[852,1209]
            button11=[941,1209]
            button2=[697,1336]
            button5=[770,1336]
            button8=[852,1336]
            button10=[941,1336]
            button3=[697,1477]
            button6=[770,1477]
            button9=[852,1477]
            button12=[941,1477]
            buttonA_13=[697,1633]
            buttonB_14=[770,1633]
            buttonC_15=[852,1633]
            buttonD_16=[941,1633]
            lowFreqs=[697,770,852,941]
            highFreqs=[1209,1336,1477,1633]
            
            
    end
    
    methods (Access = private)
        
        function [fLen,freqs] = getTargetFreqs(~,y)

            [~,index1]=max(y(1:length(y)/2));
            y(index1)=0;
            [~,index2]=max(y(1:length(y)/2));
            x_tmp=-3999:20:4000;
            if abs(x_tmp(index1))>abs(x_tmp(index2))
                tmp=x_tmp(index1);
                x_tmp(index1)=x_tmp(index2);
                x_tmp(index2)=tmp;
            end
            freqs=[abs(x_tmp(index1)),abs(x_tmp(index2))];
            fLen = length(freqs);
            
           end
        
        function [low,high]=getFreqs(app,num)
            switch num
                case 1
                    low=app.button1(1);
                    high=app.button1(2);
                case 2
                    low=app.button2(1);
                    high=app.button2(2);
                case 3
                    low=app.button3(1);
                    high=app.button3(2);
                case 4
                    low=app.button4(1);
                    high=app.button4(2);
                case 5
                    low=app.button5(1);
                    high=app.button5(2);
                case 6
                    low=app.button6(1);
                    high=app.button6(2);
                case 7
                    low=app.button7(1);
                    high=app.button7(2);
                case 8
                    low=app.button8(1);
                    high=app.button8(2);
                case 9
                    low=app.button9(1);
                    high=app.button9(2);
                case 10
                    low=app.button10(1);
                    high=app.button10(2);
                case 11
                    low=app.button11(1);
                    high=app.button11(2);
                case 12
                    low=app.button12(1);
                    high=app.button12(2);
                case 13
                    low=app.buttonA_13(1);
                    high=app.buttonA_13(2);
                case 14
                    low=app.buttonB_14(1);
                    high=app.buttonB_14(2);
                case 15
                    low=app.buttonC_15(1);
                    high=app.buttonC_15(2);
                case 16
                    low=app.buttonD_16(1);
                    high=app.buttonD_16(2);
                otherwise
                    low=0;
                    high=0;
            end
            
        end
        
        function [x,y]=calculate(~,T,fs,low,high)
           n=1:T;
           x=n./fs;
           y=sin(2.*pi.*low.*x)+sin(2.*pi.*high.*x);
        end
        
        function [y]=FFT(~,y)
            y=fftshift(abs(fft(y)))./(400/2)./(400/2);
        end
        
        function [isFind,target,error1,error2] = findTargetBtn(app,fLen,freqs)
        
            isFind = 0;
            target = '';
            error1 = inf; 
            error2 = inf;
            num=1;
            if fLen==2
                for i=1:4
                    for j=1:4
                        [low,high]=getFreqs(app,num);
                        
                        if abs(freqs(1)-low)<=15 && abs(freqs(2)-high)<=15
                            if num<10
                                target=num2str(num);
                            else
                                switch num
                                case 10
                                    target=  '0';
                                case 11
                                    target=  '*';
                                case 12
                                    target=  '#';
                                case 13
                                    target=  'A';
                                case 14
                                    target = 'B';
                                case 15
                                    target = 'C';
                                case 16
                                    target = 'D';
                                otherwise 
                                    isFind=0;
                                end
                            end
                        else
                        isFind=0;
                        end
                        num=num+1;
                    end
                end
                error1=abs(freqs(1)-low)/low;
                error2=abs(freqs(2)-high)/high;
            end
        
        end
        function [max1_index,max2_index]=getMaxTowNum(~,y)
            [~,index1]=max(y);
            y(index1)=0;
            [~,index2]=max(y);
            if index1>index2
                tmp=index1;
                index1=index2;
                index2=tmp;
            end
            max1_index=index1;
            max2_index=index2;
        end
        function drawTimeDomin(~,axes,y_sequence,style)
            
            x=1:length(y_sequence);
            plot(axes,x,y_sequence,style)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fileName)
            
            app.DTMF=mainapp;
            
            app.fileName=fileName;
            
            app.fileName_text.Value=app.fileName;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.DTMF.createASequence.Enable = 'on';
            
            delete(app)
        end

        % Button pushed function: read
        function readButtonPushed(app, event)
            
            isFind=strfind(app.fileName,'.wav');
            
            if isempty(isFind)
                fileID = fopen(app.fileName);
                C=textscan(fileID,'%f');   
                fclose(fileID);
                app.y_sequence_All=rot90(cell2mat(C));
            else
                [app.y_sequence_All,~] = audioread(app.fileName);
            end


            drawTimeDomin(app,app.UIAxes,app.y_sequence_All,'-.')
            
            
        end

        % Button pushed function: playAudio
        function playAudioButtonPushed(app, event)
            sound(app.y_sequence_All)
        end

        % Value changed function: FFTButton
        function FFTButtonValueChanged(app, event)
            app.DecodingOutPut_str='';
            app.FFT_time.Text='计算时间:';
            app.num_of_all=ceil(length(app.y_sequence_All)/800);
            tic
            for i=1:app.num_of_all               
                tmp=app.y_sequence_All(1+800*(i-1):800*i);
                app.y_sequence=tmp(201:600);
                app.y_sequence=FFT(app,app.y_sequence);
                [fLen,freqs]=getTargetFreqs(app,app.y_sequence);
                [~,target,~,~]=findTargetBtn(app,fLen,freqs);
                app.DecodingOutPut_str=sprintf('%s%s',app.DecodingOutPut_str,target);
            
            end
            t1=toc;
            app.FFT_time.Text=strcat(app.FFT_time.Text,num2str(t1));
            app.FFT_decoded_num.Text=app.DecodingOutPut_str;
            
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            app.DecodingOutPut_str='';
            app.G_time.Text='计算时间:';
            app.num_of_all=ceil(length(app.y_sequence_All)/800);
            tic
            for i=1:app.num_of_all               
                tmp=app.y_sequence_All(1+800*(i-1):800*i);
                app.y_sequence=tmp(201:600);
                
                f = [697 770 852 941 1209 1336 1477 1633];
                freq_indices = round(f/8000*400) + 1;   
                dft_data = goertzel(app.y_sequence,freq_indices);
                
                [index1,index2]=getMaxTowNum(app,abs(dft_data));
                freqs=[f(index1),f(index2)];        
                [~,target,~,~]=findTargetBtn(app,2,freqs);
                app.DecodingOutPut_str=sprintf('%s%s',app.DecodingOutPut_str,target);
            
            end
            t1=toc;
            app.G_time.Text=strcat(app.G_time.Text,num2str(t1));
            app.G_decoded_num.Text=app.DecodingOutPut_str;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件名:';
            app.fileName_text = uieditfield(app.UIFigure, 'text');
            app.read = uibutton(app.UIFigure, 'push');
            app.read.ButtonPushedFcn = createCallbackFcn(app, @readButtonPushed, true);
            app.read.Text = '读取';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TimeDomin')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FFTButton = uibutton(app.UIFigure, 'state');
            app.FFTButton.ValueChangedFcn = createCallbackFcn(app, @FFTButtonValueChanged, true);
            app.FFTButton.Text = 'FFT解码';
            app.FFT_decoded = uilabel(app.UIFigure);
            app.FFT_decoded.Text = '识别结果:';
            app.FFT_decoded_num = uilabel(app.UIFigure);
            app.FFT_decoded_num.Text = '';
            app.playAudio = uibutton(app.UIFigure, 'push');
            app.playAudio.ButtonPushedFcn = createCallbackFcn(app, @playAudioButtonPushed, true);
            app.playAudio.Text = '播放';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G解码';
            app.G_decoded = uilabel(app.UIFigure);
            app.G_decoded.Text = '识别结果:';
            app.G_decoded_num = uilabel(app.UIFigure);
            app.G_decoded_num.Text = '';
            app.FFT_time = uilabel(app.UIFigure);
            app.FFT_time.Text = '计算时间:';
            app.G_time = uilabel(app.UIFigure);
            app.G_time.Text = '计算时间:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = receiver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end